classdef snakeGameApp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        snakecolorDropDown  matlab.ui.control.DropDown
        Label_4             matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        Label_3             matlab.ui.control.Label
        Image               matlab.ui.control.Image
        DifficultyDropDown  matlab.ui.control.DropDown
        DropDown_2Label     matlab.ui.control.Label
        SpeedDropDown       matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        Score0Label         matlab.ui.control.Label
        Button              matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SnakeX            % snake的X坐标
        SnakeY            % snake的Y坐标
        Food              % Food的坐标
        Direction         % 控制snake的方向
        Timer             % 游戏循环的速度
        Score             % 游戏得分
        Speed             % Snake速度
        Difficulty        % 游戏困难程度
        Obstacles         % 障碍物位置
        Snakecolor        % 蛇颜色
    end
    
    methods (Access = private)
        
        function initializeGame(app)
            % 初始化游戏参数
            app.SnakeX = [10 9 8];
            app.SnakeY = [10 10 10];
            app.Direction = 'up';
            app.Food = app.generateFood();  % 先生成食物
            app.Obstacles = app.generateObstacles();  % 再生成障碍物
            app.Score = 0;
            app.Score0Label.Text = 'Score: 0';
            app.plotSnake();
            app.plotFood();
            app.plotObstacles();
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', app.Speed, 'TimerFcn', @(~,~) gameLoop(app));
            start(app.Timer);
        end

        function food = generateFood(app)
            while true
                food = [randi([1 20]), randi([1 20])];
                if ~any(app.SnakeX == food(1) & app.SnakeY == food(2))
                    break;
                end
            end
        end

        function obstacles = generateObstacles(app)
            % Generate obstacles based on difficulty level
            numObstacles = (app.Difficulty - 1) * 5; % More obstacles for higher difficulty
            obstacles = [];
            for i = 1:numObstacles
                while true
                    obstacle = [randi([1 20]), randi([1 20])];
                    if ~any(app.SnakeX == obstacle(1) & app.SnakeY == obstacle(2)) && ...
                       ~any(app.Food(1) == obstacle(1) & app.Food(2) == obstacle(2)) && ...
                       (isempty(obstacles) || ~any(obstacles(:,1) == obstacle(1) & obstacles(:,2) == obstacle(2)))
                        obstacles = [obstacles; obstacle];
                        break;
                    end
                end
            end
        end


        function plotSnake(app)
            % Plot the snake on the axes
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.SnakeX, app.SnakeY, 's', 'MarkerSize', 10, 'MarkerFaceColor', app.Snakecolor);
            xlim(app.UIAxes, [0 21]);
            ylim(app.UIAxes, [0 21]);
        end

        function plotFood(app)
            % Plot the food on the axes
            plot(app.UIAxes, app.Food(1), app.Food(2), 's', 'MarkerSize', 10, 'MarkerFaceColor', 'yellow');
        end

        function plotObstacles(app)

            if ~isempty(app.Obstacles)
                plot(app.UIAxes, app.Obstacles(:,1), app.Obstacles(:,2), 's', 'MarkerSize', 10, 'MarkerFaceColor', 'black');
            end
        end

        function moveSnake(app)
            % Move the snake based on the current direction
            switch app.Direction
                case 'right'
                    app.SnakeX = [app.SnakeX(1)+1, app.SnakeX(1:end-1)];
                    app.SnakeY = [app.SnakeY(1), app.SnakeY(1:end-1)];
                case 'left'
                    app.SnakeX = [app.SnakeX(1)-1, app.SnakeX(1:end-1)];
                    app.SnakeY = [app.SnakeY(1), app.SnakeY(1:end-1)];
                case 'up'
                    app.SnakeX = [app.SnakeX(1), app.SnakeX(1:end-1)];
                    app.SnakeY = [app.SnakeY(1)+1, app.SnakeY(1:end-1)];
                case 'down'
                    app.SnakeX = [app.SnakeX(1), app.SnakeX(1:end-1)];
                    app.SnakeY = [app.SnakeY(1)-1, app.SnakeY(1:end-1)];
            end
        end

        function gameLoop(app)
            % 游戏主循环
            app.moveSnake();
            
            % 检测吃到食物
            if app.SnakeX(1) == app.Food(1) && app.SnakeY(1) == app.Food(2)
                app.Score = app.Score + 1;
                app.Score0Label.Text = ['Score: ', num2str(app.Score)];
                app.Food = app.generateFood();
                app.SnakeX = [app.SnakeX, app.SnakeX(end)];
                app.SnakeY = [app.SnakeY, app.SnakeY(end)];
            end
            
            % 检测撞到墙或者自身或 障碍物
            if app.checkCollision()
                stop(app.Timer);
                uialert(app.UIFigure, '撞墙、撞到自身或撞到障碍物！', '游戏结束');
                return;
            end
            
            % Update snake and food plot
            app.plotSnake();
            app.plotFood();
            app.plotObstacles();
        end

        function collision = checkCollision(app)
            % 撞到墙
            if app.SnakeX(1) < 1 || app.SnakeX(1) > 20 || ...
                app.SnakeY(1) < 1 || app.SnakeY(1) > 20
                collision = true;
                return;
            end

            % 撞到自身
            if any(app.SnakeX(1) == app.SnakeX(2:end) & app.SnakeY(1) == app.SnakeY(2:end))
                collision = true;
                return;
            end

            %检测撞到障碍物
            if ~isempty(app.Obstacles) && any(app.SnakeX(1) == app.Obstacles(:,1) & app.SnakeY(1) == app.Obstacles(:,2))
                collision = true;
                return; 
            end

            collision = false;
        end


        function changeDirection(app, event)
            switch event.Key
                case 'rightarrow'
                    if ~strcmp(app.Direction, 'left')
                        app.Direction = 'right';
                    end
                case 'leftarrow'
                    if ~strcmp(app.Direction, 'right')
                    app.Direction = 'left';
                    end
                case 'uparrow'
                    if ~strcmp(app.Direction, 'down')
                        app.Direction = 'up';
                    end
                case 'downarrow'
                    if ~strcmp(app.Direction, 'up')
                        app.Direction = 'down';
                    end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            switch app.snakecolorDropDown.Value
                case 'Red'
                    app.Snakecolor = 'Red';
                case 'Green'
                    app.Snakecolor = 'Green';
                case 'Blue'
                    app.Snakecolor = 'Blue';
                case 'Purple'
                    app.Snakecolor = 'magenta';
            end


            switch app.SpeedDropDown.Value
                case 'Slow'
                    app.Speed = 0.5;
                case 'Medium'
                    app.Speed = 0.2;
                case 'Fast'
                    app.Speed = 0.1;
            end
            
            % Set the difficulty based on user selection
            switch app.DifficultyDropDown.Value
                case 'Easy'
                    app.Difficulty = 1;
                case 'Medium'
                    app.Difficulty = 2;
                case 'Hard'
                    app.Difficulty = 3;
            end
            app.initializeGame();
            % 设置键盘事件处理函数
            app.UIFigure.KeyPressFcn = @(src, event) app.changeDirection(event);
            % 设置焦点到 UIFigure，确保键盘事件能够捕获
            focus(app.UIFigure);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            app.UIFigure.KeyPressFcn = @(src, event) changeDirection(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            colormap(app.UIAxes, 'white')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始';
            app.Score0Label = uilabel(app.UIFigure);
            app.Score0Label.Text = 'Score: 0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '贪吃蛇快长大';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '蛇速度';
            app.SpeedDropDown = uidropdown(app.UIFigure);
            app.SpeedDropDown.Items = {'Slow', 'Medium', 'Fast'};
            app.SpeedDropDown.Tag = 'SpeedDropDown';
            app.SpeedDropDown.Value = 'Medium';
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '游戏难度';
            app.DifficultyDropDown = uidropdown(app.UIFigure);
            app.DifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.DifficultyDropDown.Tag = 'DifficultyDropDown';
            app.DifficultyDropDown.Value = 'Medium';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tcs.jpg');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '游戏说明';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'游戏难度：'; '用来控制障碍物的多少。'; 'Easy模式下没有障碍物噢！'; ''; '祝您游戏愉快！'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '蛇的颜色';
            app.snakecolorDropDown = uidropdown(app.UIFigure);
            app.snakecolorDropDown.Items = {'Red', 'Green', 'Blue', 'Purple'};
            app.snakecolorDropDown.Value = 'Green';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = snakeGameApp3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end